classdef GMEDGE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GMEDGEUIFigure       matlab.ui.Figure
        ImportGravityMagneticDataASCIIButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        dxButton             matlab.ui.control.Button
        dyButton             matlab.ui.control.Button
        dzButton             matlab.ui.control.Button
        dzzButton            matlab.ui.control.Button
        THDButton            matlab.ui.control.Button
        ASAButton            matlab.ui.control.Button
        THETAButton          matlab.ui.control.Button
        TILTButton           matlab.ui.control.Button
        PTMButton            matlab.ui.control.Button
        ETMButton            matlab.ui.control.Button
        TAHGButton           matlab.ui.control.Button
        TDXButton            matlab.ui.control.Button
        ASBButton            matlab.ui.control.Button
        THDRBButton          matlab.ui.control.Button
        EBTHDButton          matlab.ui.control.Button
        TAEBTHDButton        matlab.ui.control.Button
        NASButton            matlab.ui.control.Button
        NSASButton           matlab.ui.control.Button
        NTASButton           matlab.ui.control.Button
        MNTASButton          matlab.ui.control.Button
        TBHDButton           matlab.ui.control.Button
        HTAButton            matlab.ui.control.Button
        N_ED_AButton         matlab.ui.control.Button
        HTButton             matlab.ui.control.Button
        TAASButton           matlab.ui.control.Button
        THVHButton           matlab.ui.control.Button
        ETILTButton          matlab.ui.control.Button
        ETHDRButton          matlab.ui.control.Button
        GradientBasedEdgeDetectionLabel  matlab.ui.control.Label
        NTHDButton           matlab.ui.control.Button
        NNTHDButton          matlab.ui.control.Button
        BHDButton            matlab.ui.control.Button
        NSTDButton           matlab.ui.control.Button
        SaveDataButton       matlab.ui.control.Button
        SaveResultLabel      matlab.ui.control.Label
        NTHDButton_2         matlab.ui.control.Button
        NNTHDButton_2        matlab.ui.control.Button
        BHDButton_2          matlab.ui.control.Button
        NSTDButton_2         matlab.ui.control.Button
        NTHDButton_3         matlab.ui.control.Button
        NNTHDButton_3        matlab.ui.control.Button
        BHDButton_3          matlab.ui.control.Button
        NSTDButton_3         matlab.ui.control.Button
        MovingwindowbasedEdgeDetectionLabel  matlab.ui.control.Label
        X3MovingWindowLabel  matlab.ui.control.Label
        x5MovingWindowLabel  matlab.ui.control.Label
        X7MovingWindowLabel  matlab.ui.control.Label
        EditField            matlab.ui.control.EditField
        EXITButton           matlab.ui.control.Button
        SAVEALLButton        matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ImportGravityMagneticDataASCIIButton
        function ImportGravityMagneticDataASCIIButtonPushed(app, event)
            %% GMEDGE: APPLICATION FOR DETECTION OF EDGE IN POTENTIAL FIELD DATA
            %% DESIGNED BY: SOUMYASHREE DEBASIS SAHOO
            %% GUIDED BY: DR. SANJIT KUMAR PAL
            %% IIT(ISM) DHANBAD, JHARKHAND
            %% TESTED USING MATLAB R2018a MACOS HIGH SIERRA 10.13
            message=sprintf('select ascii grid only');
            uialert(app.GMEDGEUIFigure,message,'warning','Icon','warning');
            fileName = uigetfile('*.grd');
            [A, B] = readgrid(fileName);
            [row,col]=size(A);
            long=B(3,1):B(2,1):(B(3,1)+(B(2,1)*(col-1)));
            lat=B(3,2):B(1,2):(B(3,2)+(B(1,2)*(row-1)));
            [xx,yy]=meshgrid(long,lat);
            pcolor(app.UIAxes,xx,yy,A)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'gravity/magnetic anomaly','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'gravity',A);
            setappdata(0,'cordinate',B);
            setappdata(0,'longitude',xx);
            setappdata(0,'latitude',yy);
            setappdata(0,'xspace',B(2,1));
            setappdata(0,'yspace',B(1,2));
        end

        % Button pushed function: dxButton
        function dxButtonPushed(app, event)
            message=sprintf('First Order Horizontal Derivative in \n E-W direction. \n Maximum and minimum Associated with \n edge of source');
            uialert(app.GMEDGEUIFigure,message,'dx','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,dx)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'dx','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'dx',dx);
            setappdata(0,'dy',dy);
        end

        % Button pushed function: dyButton
        function dyButtonPushed(app, event)
            message=sprintf('First Order Horizontal Derivative in \n N-S direction. \n Maximum and minimum Associated with \n edge of source');
            uialert(app.GMEDGEUIFigure,message,'dy','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,dy)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'dy','fontname','arial','FontWeight','bold','fontsize',12);
        end

        % Button pushed function: dzButton
        function dzButtonPushed(app, event)
            message=sprintf('First Order Vertical Derivative. \n Zero contour over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'dz','Icon','info');
            A=getappdata(0,'gravity');
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,dz)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'dz','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'dz',dz);
        end

        % Button pushed function: dzzButton
        function dzzButtonPushed(app, event)
            message=sprintf('Second Order Vertical Derivative. \n Zero contour over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'dzz','Icon','info');
            A=getappdata(0,'gravity');
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            dzz=vertical(dz,npts,nc,nr,1); 
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,dzz)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'second vertical derivative','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'dzz',dzz);
        end

        % Button pushed function: THDButton
        function THDButtonPushed(app, event)
            message=sprintf('Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'THD','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt((dx.^2)+(dy.^2));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,thd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Total Horizontal Derivative','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'thd',thd);
        end

        % Button pushed function: ASAButton
        function ASAButtonPushed(app, event)
            message=sprintf('Analytical Signal Amplitude. \n Maximum amplitude on the map  \n associated with edge of source');
            uialert(app.GMEDGEUIFigure,message,'ASA','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,asa)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Analytic Signal Amplitude','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'asa',asa);
        end

        % Button pushed function: THETAButton
        function THETAButtonPushed(app, event)
            message=sprintf('THETA MAP. \n Minimum of the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'dz','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            thd=sqrt((dx.^2)+(dy.^2));
            theta=acos(thd./asa);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,theta)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Theta Map','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'theta',theta)
        end

        % Button pushed function: TILTButton
        function TILTButtonPushed(app, event)
            message=sprintf('TILT ANGLE. \n Zero contour over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'TILT ANGLE','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            thd=sqrt((dx.^2)+(dy.^2));
            tilt=atan(dz./thd);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,tilt)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Tilt Angle','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'tilt',tilt);
        end

        % Button pushed function: PTMButton
        function PTMButtonPushed(app, event)
            message=sprintf('Power Transform of Theta Map. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'PTM','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            thd=sqrt((dx.^2)+(dy.^2));
            theta=acos(thd./asa);
            ptm=(cos(theta)).^4;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,ptm)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Power Transform Theta Map','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'ptm',ptm)
        end

        % Button pushed function: ETMButton
        function ETMButtonPushed(app, event)
            message=sprintf('Exponential transform Theta Map. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'ETM','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            thd=sqrt((dx.^2)+(dy.^2));
            theta=acos(thd./asa);
            etm=exp(4.*cos(theta));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,etm)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Exponential Transform Theta Map','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'etm',etm)
        end

        % Button pushed function: TAHGButton
        function TAHGButtonPushed(app, event)
            message=sprintf('Tilt Angle of THD. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'TAHG','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt((dx.^2)+(dy.^2));
            [nr,nc]=size(thd); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            thd_z=vertical(thd,npts,nc,nr,1);
            [thd_x,thd_y]=gradient(thd);
            tahg=atan(thd_z./(sqrt(thd_x.^2 + thd_y.^2)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,tahg)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Tilt Angle of THD','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'tahg',tahg)
        end

        % Button pushed function: TDXButton
        function TDXButtonPushed(app, event)
            message=sprintf('TDX. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'TDX','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            thd=sqrt((dx.^2)+(dy.^2));
            tdx=atan(thd./(abs(dz)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,tdx)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Tdx','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'tdx',tdx);
        end

        % Button pushed function: ASBButton
        function ASBButtonPushed(app, event)
            message=sprintf('Balanced Analytic Signal. \n Maximum Amplitude over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'ASB','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            [asax,asay]=gradient(asa);
            c1=imag(hilbert(asax));
            c2=imag(hilbert(asay));
            asb=asa./(1+sqrt(c1.^2 + c2.^2 + asa.^2));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,asb)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Balanced Analytic Signal','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'asb',asb)
        end

        % Button pushed function: THDRBButton
        function THDRBButtonPushed(app, event)
            message=sprintf('Balanced Total Horizontal Derivative. \n maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'THDRB','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt((dx.^2)+(dy.^2));
            [thdx,thdy]=gradient(thd);
            c1=imag(hilbert(thdx));
            c2=imag(hilbert(thdy));
            thdrb=thd./(1+sqrt(c1.^2 + c2.^2 + thd.^2));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,thdrb)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Balanced Total Horizontal Derivative','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'thdrb',thdrb)
        end

        % Button pushed function: EBTHDButton
        function EBTHDButtonPushed(app, event)
            message=sprintf('Enhanced Balanced Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'EBTHD','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            thd=sqrt((dx.^2)+(dy.^2));
            [asx,asy]=gradient(asa);
            c1=imag(hilbert(asx));
            c2=imag(hilbert(asy));
            ebthd=thd./(1+(sqrt(c1.^2 + c2.^2 + asa.^2)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,ebthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Enhanced Balanced Total Horizontal Derivative','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'ebthd',ebthd);
        end

        % Button pushed function: TAEBTHDButton
        function TAEBTHDButtonPushed(app, event)
            message=sprintf('Tilt Angle of EBTHD. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'TAEBTHD','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            thd=sqrt((dx.^2)+(dy.^2));
            [asx,asy]=gradient(asa);
            c1=imag(hilbert(asx));
            c2=imag(hilbert(asy));
            ebthd=thd./(1+(sqrt(c1.^2 + c2.^2 + asa.^2)));
            [ex,ey]=gradient(ebthd);
            ez=vertical(ebthd,npts,nc,nr,1);
            c3=sqrt(ex.^2 + ey.^2);
            taebthd=atan(ez./c3);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,taebthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Tilt Angle of EBTHD','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'taebthd',taebthd)
        end

        % Button pushed function: NASButton
        function NASButtonPushed(app, event)
            message=sprintf('NAS. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NAS','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            p=0.25;
            nas=atan(asa./((abs(dz))+(p.*max(max(asa)))));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nas)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'NAS','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nas',nas)
        end

        % Button pushed function: NSASButton
        function NSASButtonPushed(app, event)
            message=sprintf('NSAS. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NSAS','Icon','info'); 
            A=getappdata(0,'gravity');
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            dzz=vertical(dz,npts,nc,nr,1); 
            [dzx,dzy]=gradient(dz);
            p=0.25;
            sas=sqrt(dzx.^2 + dzy.^2 + dzz.^2);
            nsas=atan(sas./((abs(dzz))+(p.*max(max(sas)))));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nsas)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'NSAS','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nsas',nsas)
        end

        % Button pushed function: NTASButton
        function NTASButtonPushed(app, event)
            message=sprintf('NTAS. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NTAS','Icon','info');
            A=getappdata(0,'gravity');
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            dzz=vertical(dz,npts,nc,nr,1); 
            dzzz=vertical(dzz,npts,nc,nr,1);
            [dzzx,dzzy]=gradient(dzz);
            tas=sqrt(dzzx.^2 + dzzy.^2 + dzzz.^2);
            ntas=atan(tas./abs(dzzz));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,ntas)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'NTAS','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'ntas',ntas)
        end

        % Button pushed function: MNTASButton
        function MNTASButtonPushed(app, event)
            message=sprintf('MNTAS. \n Minimum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'MNTAS','Icon','info');
            A=getappdata(0,'gravity');
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            dzz=vertical(dz,npts,nc,nr,1); 
            dzzz=vertical(dzz,npts,nc,nr,1);
            [dzzx,dzzy]=gradient(dzz);
            tas=sqrt(dzzx.^2 + dzzy.^2 + dzzz.^2);
            c1=min(min(dzz))./max(max(dzzz));
            c2=max(max(tas.*c1));
            p=0.25;
            mntas=atan((tas.*c1)./(abs(dzz)+p.*c2));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,mntas)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'MNTAS','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'mntas',mntas)
        end

        % Button pushed function: TBHDButton
        function TBHDButtonPushed(app, event)
            message=sprintf('Tilt Angle of Balanced THD. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'TBHD','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax); 
            thd=sqrt((dx.^2)+(dy.^2));
            [thdx,thdy]=gradient(thd);
            c1=imag(hilbert(thdx));
            c2=imag(hilbert(thdy));
            thdrb=thd./(1+sqrt(c1.^2 + c2.^2 + thd.^2));
            [thdrbx,thdrby]=gradient(thdrb);
            thdrbz=vertical(thdrb,npts,nc,nr,1);
            tbhd=atan(thdrbz./sqrt(thdrbx.^2 + thdrby.^2));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,tbhd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Tilt Angle of Balanced THD','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'tbhd',tbhd)
        end

        % Button pushed function: HTAButton
        function HTAButtonPushed(app, event)
            message=sprintf('Hyperbolic Tilt Angle. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'HTA','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            thd=sqrt((dx.^2)+(dy.^2));
            hta=real(atanh(dz./thd));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,hta)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Hyperbolic Tilt Angle','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'hta',hta);
        end

        % Button pushed function: N_ED_AButton
        function N_ED_AButtonPushed(app, event)
            message=sprintf('N_ED_A. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'N_ED_A','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            dzz=vertical(dz,npts,nc,nr,1);
            dxz=vertical(dx,npts,nc,nr,1);
            dyz=vertical(dy,npts,nc,nr,1);
            [dxx,dxy]=gradient(dx);
            [dyx,dyy]=gradient(dy);
            neda=atan((sqrt(dxx.^2 + dxy.^2 +dxz.^2 + dyx.^2 + dyy.^2 + dyz.^2))./abs(dzz));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,neda)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'N_Ed_A','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'neda',neda);
        end

        % Button pushed function: HTButton
        function HTButtonPushed(app, event)
            message=sprintf('Total Horizontal Derivative of Tilt Angle. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'HT','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            ty=sqrt((dx.^2)+(dz.^2));
            tilt_y=atan(dy./ty);
            tx=sqrt((dz.^2)+(dy.^2));
            tilt_x=atan(dx./tx);
            HT=sqrt(tilt_x.^2 + tilt_y.^2);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,HT)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Total Horizontal Derivative of Tilt Angle','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'HT',HT);
        end

        % Button pushed function: TAASButton
        function TAASButtonPushed(app, event)
            message=sprintf('Enhanced Analytic Signal Tilt Angle. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'TAAS','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            [asx,asy]=gradient(asa);
            asz=vertical(asa,npts,nc,nr,1);
            c1=sqrt(asx.^2 + asy.^2);
            taas=atan(asz./c1);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,taas)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Enhanced Analytic Signal Tilt Angle','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'taas',taas);
        end

        % Button pushed function: THVHButton
        function THVHButtonPushed(app, event)
            message=sprintf('Tilt Angle of vertical derivative of THD. \n Maximum Amplitude over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'THVH','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt((dx.^2)+(dy.^2));
            [nr,nc]=size(thd); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            thv=vertical(thd,npts,nc,nr,1);
            thv_z=vertical(thv,npts,nc,nr,1);
            [thv_x,thv_y]=gradient(thv);
            thvh=atan(thv_z./(sqrt(thv_x.^2 + thv_y.^2)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,thvh)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Tilt Angle of vertical derivative of THD','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'thvh',thvh)
        end

        % Button pushed function: ETILTButton
        function ETILTButtonPushed(app, event)
            message=sprintf('Enhanced Tilt Angle. \n Maximum Amplitude over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'ETILT','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            [asx,asy]=gradient(asa);
            spacex=getappdata(0,'xspace');
            spacey=getappdata(0,'yspace');
            K=1./(sqrt((spacex.^2)+(spacey.^2)));
            etilt=atan((K.*dz)./(sqrt(asx.^2 + asy.^2)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,etilt)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Enhanced Tilt Angle','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'etilt',etilt);
        end

        % Button pushed function: ETHDRButton
        function ETHDRButtonPushed(app, event)
            message=sprintf('Total Horizontal Derivative of Enhanced Tilt Angle. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'ETHDR','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1); 
            asa=sqrt((dx.^2)+(dy.^2)+(dz.^2));
            [asx,asy]=gradient(asa);
            spacex=getappdata(0,'xspace');
            spacey=getappdata(0,'yspace');
            K=1./(sqrt((spacex.^2)+(spacey.^2)));
            etilt=atan((K.*dz)./(sqrt(asx.^2 + asy.^2)));
            [etiltx,etilty]=gradient(etilt);
            ethdr=sqrt(etiltx.^2 + etilty.^2);
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,ethdr)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Total Horizontal Derivative of Enhanced Tilt Angle','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'ethdr',ethdr);
        end

        % Button pushed function: NTHDButton
        function NTHDButtonPushed(app, event)
            message=sprintf('Normalized Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NTHD (3X3)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt(dx.^2 + dy.^2);
            %% computation of NTHD
                [rows,columns]=size(thd);
                c=zeros(rows,columns);
                d=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+1,j+1)=thd(i,j);
                    end
                end
                for i=1:1:rows
                    d(i+1,1)=thd(i,1);
                    d(i+1,columns+2)=thd(i,columns);
                end
                for i=1:1:columns+2
                    d(1,i)=d(2,i);
                    d(rows+2,i)=d(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e=d(i-1:i+1,l-1:l+1);
                    f=e;
                    for j=1:1:3
                        for k=1:1:3
                            if(f(1,1)<f(j,k))
                                f(1,1)=f(j,k);
                            end
                        end
                    end
                   
                    c(i-1,l-1)=e(2,2)/f(1,1);
                end
                end
                nthd=c;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Normalized Total Horizontal Derivative (3X3)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nthd3_3',nthd)
        end

        % Button pushed function: NNTHDButton
        function NNTHDButtonPushed(app, event)
            message=sprintf('New Normalized Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NNTHD (3X3)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt(dx.^2 + dy.^2);
                [rows,columns]=size(thd);
                c=zeros(rows,columns);
                d=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+1,j+1)=thd(i,j);
                    end
                end
                for i=1:1:rows
                    d(i+1,1)=thd(i,1);
                    d(i+1,columns+2)=thd(i,columns);
                end
                for i=1:1:columns+2
                    d(1,i)=d(2,i);
                    d(rows+2,i)=d(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e=d(i-1:i+1,l-1:l+1);
                    f=e;
                    for j=1:1:3
                        for k=1:1:3
                            c(i-1,l-1)=e(2,2)/(mean(mean(f)));
                        end
                    end
                end
                end
                nnthd=c;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nnthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'New Normalized Total Horizontal Derivative (3X3)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nnthd3_3',nnthd)
        end

        % Button pushed function: BHDButton
        function BHDButtonPushed(app, event)
            message=sprintf('Balanced Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'BHD (3X3)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1);
            dzz=vertical(dz,npts,nc,nr,1);
                [rows,columns]=size(dz);
                c=zeros(rows,columns);
                d=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+1,j+1)=dz(i,j);
                    end
                end
                for i=1:1:rows
                    d(i+1,1)=dz(i,1);
                    d(i+1,columns+2)=dz(i,columns);
                end
                for i=1:1:columns+2
                    d(1,i)=d(2,i);
                    d(rows+2,i)=d(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e=d(i-1:i+1,l-1:l+1);
                    f=e;
                    for j=1:1:3
                        for k=1:1:3
                           c(i-1,l-1)=mean(mean(f));
                                       
                        end
                    end
                end
                end
                
                % mean of second order vertical derivative
                [row,column]=size(dzz);
                p=zeros(row,column);
                q=zeros(row+2,column+2);
                for i=1:1:row
                    for j=1:1:column
                        q(i+1,j+1)=dzz(i,j);
                    end
                end
                for i=1:1:row
                    q(i+1,1)=dzz(i,1);
                    q(i+1,column+2)=dzz(i,column);
                end
                for i=1:1:column+2
                    q(1,i)=q(2,i);
                    q(row+2,i)=q(row+1,i);
                end
                for l=2:1:column+1
                for i=2:1:row+1
                    r=q(i-1:i+1,l-1:l+1);
                    s=r;
                    for j=1:1:3
                        for k=1:1:3
                      p(i-1,l-1)=mean(mean(s));
                                       
                        end
                    end
                end
                end
                
                % calculation of ratio of means
                t=c./p;
                bhd=abs(atan(sqrt(dx.^2+dy.^2)./(t.*dzz)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,bhd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Balanced Horizontal Derivative (3X3)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'bhd3_3',bhd)
        end

        % Button pushed function: NSTDButton
        function NSTDButtonPushed(app, event)
            message=sprintf('Normalized Standard Deviation. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NSTD (3X3)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1);
                %% standard Deviation of dx
                [rows,columns]=size(dx);
                c_dx=zeros(rows,columns);
                d_dx=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dx(i+1,j+1)=dx(i,j);
                    end
                end
                for i=1:1:rows
                    d_dx(i+1,1)=dx(i,1);
                    d_dx(i+1,columns+2)=dx(i,columns);
                end
                for i=1:1:columns+2
                    d_dx(1,i)=d_dx(2,i);
                    d_dx(rows+2,i)=d_dx(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e_dx=d_dx(i-1:i+1,l-1:l+1);
                    f_dx=e_dx;
                    for j=1:1:3
                        for k=1:1:3
                            
                                c_dx(i-1,l-1)=std(reshape(f_dx,1,(3*3)));
                           
                        end
                    end
                end
                end
                %% standard deviation of dy
                [rows,columns]=size(dy);
                c_dy=zeros(rows,columns);
                d_dy=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dy(i+1,j+1)=dy(i,j);
                    end
                end
                for i=1:1:rows
                    d_dy(i+1,1)=dy(i,1);
                    d_dy(i+1,columns+2)=dy(i,columns);
                end
                for i=1:1:columns+2
                    d_dy(1,i)=d_dy(2,i);
                    d_dy(rows+2,i)=d_dy(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e_dy=d_dy(i-1:i+1,l-1:l+1);
                    f_dy=e_dy;
                    for j=1:1:3
                        for k=1:1:3
                            
                                c_dy(i-1,l-1)=std(reshape(f_dy,1,(3*3)));
                           
                        end
                    end
                end
                end
                %% standard deviation of dz
                [rows,columns]=size(dz);
                c_dz=zeros(rows,columns);
                d_dz=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dz(i+1,j+1)=dz(i,j);
                    end
                end
                for i=1:1:rows
                    d_dz(i+1,1)=dz(i,1);
                    d_dz(i+1,columns+2)=dz(i,columns);
                end
                for i=1:1:columns+2
                    d_dz(1,i)=d_dz(2,i);
                    d_dz(rows+2,i)=d_dz(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e_dz=d_dz(i-1:i+1,l-1:l+1);
                    f_dz=e_dz;
                    for j=1:1:3
                        for k=1:1:3
                            
                                c_dz(i-1,l-1)=std(reshape(f_dz,1,(3*3)));
                           
                        end
                    end
                end
                end
                
                nstd=c_dz./(c_dx+c_dy+c_dz) ;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nstd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Normalized Standard Deviation (3X3)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nstd3_3',nstd)
        end

        % Button pushed function: NTHDButton_2
        function NTHDButton_2Pushed(app, event)
            message=sprintf('Normalized Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NTHD (5X5)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt(dx.^2 + dy.^2);
            [rows,columns]=size(thd);
                c=zeros(rows,columns);
                d=zeros(rows+4,columns+4);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+2,j+2)=thd(i,j);
                    end
                end
                for i=1:1:rows
                    d(i+2,2)=thd(i,1);
                    d(i+2,columns+3)=thd(i,columns);
                end
                for i=1:1:rows
                    d(i+2,1)=thd(i,2);
                    d(i+2,columns+4)=thd(i,columns-1);
                end
                for i=1:1:columns+4
                    d(2,i)=d(3,i);
                    d(rows+3,i)=d(rows+2,i);
                end
                for i=1:1:columns+4
                    d(1,i)=d(4,i);
                    d(rows+4,i)=d(rows+1,i);
                end
                for l=3:1:columns+2
                for i=3:1:rows+2
                    e=d(i-2:i+2,l-2:l+2);
                    f=e;
                    for j=1:1:5
                        for k=1:1:5
                         if(f(1,1)<f(j,k))
                            f(1,1)=f(j,k);
                         end
                        end
                    end
                    c(i-2,l-2)=e(3,3)/f(1,1);
                end
                end
                nthd=c;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Normalized Total Horizontal Derivative (5X5)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nthd5_5',nthd)
        end

        % Button pushed function: NNTHDButton_2
        function NNTHDButton_2Pushed(app, event)
            message=sprintf('New Normalized Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NNTHD (5X5)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt(dx.^2 + dy.^2);
                [rows,columns]=size(thd);
                c=zeros(rows,columns);
                d=zeros(rows+4,columns+4);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+2,j+2)=thd(i,j);
                    end
                end
                for i=1:1:rows
                    d(i+2,2)=thd(i,1);
                    d(i+2,columns+3)=thd(i,columns);
                end
                for i=1:1:rows
                    d(i+2,1)=thd(i,2);
                    d(i+2,columns+4)=thd(i,columns-1);
                end
                for i=1:1:columns+4
                    d(2,i)=d(3,i);
                    d(rows+3,i)=d(rows+2,i);
                end
                for i=1:1:columns+4
                    d(1,i)=d(4,i);
                    d(rows+4,i)=d(rows+1,i);
                end
                for l=3:1:columns+2
                for i=3:1:rows+2
                    e=d(i-2:i+2,l-2:l+2);
                    f=e;
                    for j=1:1:5
                        for k=1:1:5
                         
                            c(i-2,l-2)=e(3,3)/(mean(mean(f)));
                            
                        end
                    end
                end
                end
                nnthd=c;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nnthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'New Normalized Total Horizontal Derivative (5X5)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nnthd5_5',nnthd);
        end

        % Button pushed function: BHDButton_2
        function BHDButton_2Pushed(app, event)
            message=sprintf('Balanced Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'BHD (5X5)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1);
            dzz=vertical(dz,npts,nc,nr,1);
                [rows,columns]=size(dz);
                c=zeros(rows,columns);
                d=zeros(rows+4,columns+4);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+2,j+2)=dz(i,j);
                    end
                end
                for i=1:1:rows
                    d(i+2,2)=dz(i,1);
                    d(i+2,columns+3)=dz(i,columns);
                end
                for i=1:1:rows
                    d(i+2,1)=dz(i,2);
                    d(i+2,columns+4)=dz(i,columns-1);
                end
                for i=1:1:columns+4
                    d(2,i)=d(3,i);
                    d(rows+3,i)=d(rows+2,i);
                end
                for i=1:1:columns+4
                    d(1,i)=d(4,i);
                    d(rows+4,i)=d(rows+1,i);
                end
                for l=3:1:columns+2
                for i=3:1:rows+2
                    e=d(i-2:i+2,l-2:l+2);
                    f=e;
                    for j=1:1:5
                        for k=1:1:5
                         
                            c(i-2,l-2)=mean(mean(f));
                            
                        end
                    end
                end
                end
                
                % mean of second order vertical derivative
                [row,column]=size(dzz);
                p=zeros(row,column);
                q=zeros(row+4,column+4);
                for i=1:1:row
                    for j=1:1:column
                        q(i+2,j+2)=dzz(i,j);
                    end
                end
                for i=1:1:row
                    q(i+2,2)=dzz(i,1);
                    q(i+2,column+3)=dzz(i,column);
                end
                for i=1:1:row
                    q(i+2,1)=dzz(i,2);
                    q(i+2,column+4)=dzz(i,column-1);
                end
                for i=1:1:column+4
                    q(2,i)=q(3,i);
                    q(row+3,i)=q(row+2,i);
                end
                for i=1:1:column+4
                    q(1,i)=q(4,i);
                    q(row+4,i)=q(row+1,i);
                end
                for l=3:1:column+2
                for i=3:1:row+2
                    r=q(i-2:i+2,l-2:l+2);
                    s=r;
                    for j=1:1:5
                        for k=1:1:5
                                       
                                p(i-2,l-2)=mean(mean(s));
                            
                        end
                    end
                end
                end
                % calculation of ratio of means
                t=c./p;
                bhd=abs(atan(sqrt(dx.^2+dy.^2)./(t.*dzz)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,bhd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Balanced Horizontal Derivative (5X5)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'bhd5_5',bhd)
        end

        % Button pushed function: NSTDButton_2
        function NSTDButton_2Pushed(app, event)
            message=sprintf('Normalized Standard Deviation. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NSTD (5X5)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1);
                 %% standard deviation of dx
                [rows,columns]=size(dx);
                c_dx=zeros(rows,columns);
                d_dx=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dx(i+1,j+1)=dx(i,j);
                    end
                end
                for i=1:1:rows
                    d_dx(i+1,1)=dx(i,1);
                    d_dx(i+1,columns+2)=dx(i,columns);
                end
                for i=1:1:columns+2
                    d_dx(1,i)=d_dx(2,i);
                    d_dx(rows+2,i)=d_dx(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e_dx=d_dx(i-1:i+1,l-1:l+1);
                    f_dx=e_dx;
                    for j=1:1:3
                        for k=1:1:3
                            
                                c_dx(i-1,l-1)=std(reshape(f_dx,1,(3*3)));
                           
                        end
                    end
                end
                end
                %% standard deviation of dy
                [rows,columns]=size(dy);
                c_dy=zeros(rows,columns);
                d_dy=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dy(i+1,j+1)=dy(i,j);
                    end
                end
                for i=1:1:rows
                    d_dy(i+1,1)=dy(i,1);
                    d_dy(i+1,columns+2)=dy(i,columns);
                end
                for i=1:1:columns+2
                    d_dy(1,i)=d_dy(2,i);
                    d_dy(rows+2,i)=d_dy(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e_dy=d_dy(i-1:i+1,l-1:l+1);
                    f_dy=e_dy;
                    for j=1:1:3
                        for k=1:1:3
                            
                                c_dy(i-1,l-1)=std(reshape(f_dy,1,(3*3)));
                           
                        end
                    end
                end
                end
                %% standard deviation of dz
                [rows,columns]=size(dz);
                c_dz=zeros(rows,columns);
                d_dz=zeros(rows+2,columns+2);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dz(i+1,j+1)=dz(i,j);
                    end
                end
                for i=1:1:rows
                    d_dz(i+1,1)=dz(i,1);
                    d_dz(i+1,columns+2)=dz(i,columns);
                end
                for i=1:1:columns+2
                    d_dz(1,i)=d_dz(2,i);
                    d_dz(rows+2,i)=d_dz(rows+1,i);
                end
                for l=2:1:columns+1
                for i=2:1:rows+1
                    e_dz=d_dz(i-1:i+1,l-1:l+1);
                    f_dz=e_dz;
                    for j=1:1:3
                        for k=1:1:3
                            
                                c_dz(i-1,l-1)=std(reshape(f_dz,1,(3*3)));
                           
                        end
                    end
                end
                end
                
            nstd=c_dz./(c_dx+c_dy+c_dz) ;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nstd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Normalized Standard Deviation (5X5)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nstd5_5',nstd)
        end

        % Button pushed function: NTHDButton_3
        function NTHDButton_3Pushed(app, event)
            message=sprintf('Normalized Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NTHD (7X7)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt(dx.^2 + dy.^2);
                [rows,columns]=size(thd);
                z=zeros(rows,columns);
                d=zeros(rows+6,columns+6);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+3,j+3)=thd(i,j);
                    end
                end
                
                for i=1:1:rows
                    d(i+3,3)=thd(i,1);
                    d(i+3,columns+4)=thd(i,columns);
                end
                for i=1:1:rows
                    d(i+3,2)=thd(i,2);
                    d(i+3,columns+5)=thd(i,columns-1);
                end
                for i=1:1:rows
                    d(i+3,1)=thd(i,3);
                    d(i+3,columns+6)=thd(i,columns-2);
                end
                
                for i=1:1:columns+6
                    d(3,i)=d(4,i);
                    d(rows+4,i)=d(rows+3,i);
                end
                for i=1:1:columns+6
                    d(2,i)=d(5,i);
                    d(rows+5,i)=d(rows+2,i);
                end
                for i=1:1:columns+6
                    d(1,i)=d(6,i);
                    d(rows+6,i)=d(rows+1,i);
                end
                for l=4:1:columns+3
                for i=4:1:rows+3
                    e=d(i-3:i+3,l-3:l+3);
                    f=e;
                    for j=1:1:7
                        for k=1:1:7
                         if(f(1,1)<f(j,k))
                            f(1,1)=f(j,k);
                         end
                            
                        end
                    end
                    z(i-3,l-3)=e(4,4)/f(1,1);       
                end
                end
                nthd=z;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Normalized Total Horizontal Derivative (7X7)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nthd7_7',nthd)
        end

        % Button pushed function: NNTHDButton_3
        function NNTHDButton_3Pushed(app, event)
            message=sprintf('New Normalized Total Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NNTHD (7X7)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            thd=sqrt(dx.^2 + dy.^2);
                [rows,columns]=size(thd);
                z=zeros(rows,columns);
                d=zeros(rows+6,columns+6);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+3,j+3)=thd(i,j);
                    end
                end
                
                for i=1:1:rows
                    d(i+3,3)=thd(i,1);
                    d(i+3,columns+4)=thd(i,columns);
                end
                for i=1:1:rows
                    d(i+3,2)=thd(i,2);
                    d(i+3,columns+5)=thd(i,columns-1);
                end
                for i=1:1:rows
                    d(i+3,1)=thd(i,3);
                    d(i+3,columns+6)=thd(i,columns-2);
                end
                
                for i=1:1:columns+6
                    d(3,i)=d(4,i);
                    d(rows+4,i)=d(rows+3,i);
                end
                for i=1:1:columns+6
                    d(2,i)=d(5,i);
                    d(rows+5,i)=d(rows+2,i);
                end
                for i=1:1:columns+6
                    d(1,i)=d(6,i);
                    d(rows+6,i)=d(rows+1,i);
                end
                for l=4:1:columns+3
                for i=4:1:rows+3
                    e=d(i-3:i+3,l-3:l+3);
                    f=e;
                    for j=1:1:7
                        for k=1:1:7
                         
                            z(i-3,l-3)=e(4,4)/(mean(mean(f)));
                            
                        end
                    end
                end
                end
                nnthd=z;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nnthd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'New Normalized Total Horizontal Derivative (7X7)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nnthd7_7',nnthd)
        end

        % Button pushed function: BHDButton_3
        function BHDButton_3Pushed(app, event)
            message=sprintf('Balanced Horizontal Derivative. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'BHD (7X7)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1);
            dzz=vertical(dz,npts,nc,nr,1);
                [rows,columns]=size(dz);
                z=zeros(rows,columns);
                d=zeros(rows+6,columns+6);
                for i=1:1:rows
                    for j=1:1:columns
                        d(i+3,j+3)=dz(i,j);
                    end
                end
                
                for i=1:1:rows
                    d(i+3,3)=dz(i,1);
                    d(i+3,columns+4)=dz(i,columns);
                end
                for i=1:1:rows
                    d(i+3,2)=dz(i,2);
                    d(i+3,columns+5)=dz(i,columns-1);
                end
                for i=1:1:rows
                    d(i+3,1)=dz(i,3);
                    d(i+3,columns+6)=dz(i,columns-2);
                end
                
                for i=1:1:columns+6
                    d(3,i)=d(4,i);
                    d(rows+4,i)=d(rows+3,i);
                end
                for i=1:1:columns+6
                    d(2,i)=d(5,i);
                    d(rows+5,i)=d(rows+2,i);
                end
                for i=1:1:columns+6
                    d(1,i)=d(6,i);
                    d(rows+6,i)=d(rows+1,i);
                end
                for l=4:1:columns+3
                for i=4:1:rows+3
                    e=d(i-3:i+3,l-3:l+3);
                    f=e;
                    for j=1:1:7
                        for k=1:1:7
                         
                            z(i-3,l-3)=mean(mean(f));
                            
                        end
                    end
                end
                end
                % mean of second order vertical derivative
                [rows,columns]=size(dzz);
                p=zeros(rows,columns);
                q=zeros(rows+6,columns+6);
                for i=1:1:rows
                    for j=1:1:columns
                        q(i+3,j+3)=dzz(i,j);
                    end
                end
                
                for i=1:1:rows
                    q(i+3,3)=dzz(i,1);
                    q(i+3,columns+4)=dzz(i,columns);
                end
                for i=1:1:rows
                    q(i+3,2)=dzz(i,2);
                    q(i+3,columns+5)=dzz(i,columns-1);
                end
                for i=1:1:rows
                    q(i+3,1)=dzz(i,3);
                    q(i+3,columns+6)=dzz(i,columns-2);
                end
                
                for i=1:1:columns+6
                    q(3,i)=q(4,i);
                    q(rows+4,i)=q(rows+3,i);
                end
                for i=1:1:columns+6
                    q(2,i)=q(5,i);
                    q(rows+5,i)=q(rows+2,i);
                end
                for i=1:1:columns+6
                    q(1,i)=q(6,i);
                    q(rows+6,i)=q(rows+1,i);
                end
                
                for l=4:1:columns+3
                for i=4:1:rows+3
                    s=q(i-3:i+3,l-3:l+3);
                    r=s;
                    for j=1:1:7
                        for k=1:1:7
                         
                            p(i-3,l-3)=mean(mean(r));
                            
                        end
                    end
                end
                end
                % calculation of ratio of means
                t=z./p;
                bhd=abs(atan(sqrt(dx.^2+dy.^2)./(t.*dzz)));
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,bhd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Balanced Horizontal Derivative (7X7)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'bhd7_7',bhd)
        end

        % Button pushed function: NSTDButton_3
        function NSTDButton_3Pushed(app, event)
            message=sprintf('Normalized Standard Deviation. \n Maximum over the map associated  \n with edge of source');
            uialert(app.GMEDGEUIFigure,message,'NSTD (7X7)','Icon','info');
            A=getappdata(0,'gravity');
            [dx, dy]=gradient(A);
            [nr,nc]=size(A); 
            nmax=max([nr nc]); 
            npts=2^nextpow2(nmax);  
            dz=vertical(A,npts,nc,nr,1);
                %% standard deviation of dx
                [rows,columns]=size(dx);
                z_dx=zeros(rows,columns);
                d_dx=zeros(rows+6,columns+6);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dx(i+3,j+3)=dx(i,j);
                    end
                end
                
                for i=1:1:rows
                    d_dx(i+3,3)=dx(i,1);
                    d_dx(i+3,columns+4)=dx(i,columns);
                end
                for i=1:1:rows
                    d_dx(i+3,2)=dx(i,2);
                    d_dx(i+3,columns+5)=dx(i,columns-1);
                end
                for i=1:1:rows
                    d_dx(i+3,1)=dx(i,3);
                    d_dx(i+3,columns+6)=dx(i,columns-2);
                end
                
                for i=1:1:columns+6
                    d_dx(3,i)=d_dx(4,i);
                    d_dx(rows+4,i)=d_dx(rows+3,i);
                end
                for i=1:1:columns+6
                    d_dx(2,i)=d_dx(5,i);
                    d_dx(rows+5,i)=d_dx(rows+2,i);
                end
                for i=1:1:columns+6
                    d_dx(1,i)=d_dx(6,i);
                    d_dx(rows+6,i)=d_dx(rows+1,i);
                end
                for l=4:1:columns+3
                for i=4:1:rows+3
                    e_dx=d_dx(i-3:i+3,l-3:l+3);
                    f_dx=e_dx;
                    for j=1:1:7
                        for k=1:1:7
                         
                            z_dx(i-3,l-3)=std(reshape(f_dx,1,(7*7)));
                            
                        end
                    end
                end
                end
                %% standard deviation of dy
                [rows,columns]=size(dy);
                z_dy=zeros(rows,columns);
                d_dy=zeros(rows+6,columns+6);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dy(i+3,j+3)=dy(i,j);
                    end
                end
                
                for i=1:1:rows
                    d_dy(i+3,3)=dy(i,1);
                    d_dy(i+3,columns+4)=dy(i,columns);
                end
                for i=1:1:rows
                    d_dy(i+3,2)=dy(i,2);
                    d_dy(i+3,columns+5)=dy(i,columns-1);
                end
                for i=1:1:rows
                    d_dy(i+3,1)=dy(i,3);
                    d_dy(i+3,columns+6)=dy(i,columns-2);
                end
                
                for i=1:1:columns+6
                    d_dy(3,i)=d_dy(4,i);
                    d_dy(rows+4,i)=d_dy(rows+3,i);
                end
                for i=1:1:columns+6
                    d_dy(2,i)=d_dy(5,i);
                    d_dy(rows+5,i)=d_dy(rows+2,i);
                end
                for i=1:1:columns+6
                    d_dy(1,i)=d_dy(6,i);
                    d_dy(rows+6,i)=d_dy(rows+1,i);
                end
                for l=4:1:columns+3
                for i=4:1:rows+3
                    e_dy=d_dy(i-3:i+3,l-3:l+3);
                    f_dy=e_dy;
                    for j=1:1:7
                        for k=1:1:7
                         
                            z_dy(i-3,l-3)=std(reshape(f_dy,1,(7*7)));
                            
                        end
                    end
                end
                end
                %% standard deviation of dz
                [rows,columns]=size(dz);
                z_dz=zeros(rows,columns);
                d_dz=zeros(rows+6,columns+6);
                for i=1:1:rows
                    for j=1:1:columns
                        d_dz(i+3,j+3)=dz(i,j);
                    end
                end
                
                for i=1:1:rows
                    d_dz(i+3,3)=dz(i,1);
                    d_dz(i+3,columns+4)=dz(i,columns);
                end
                for i=1:1:rows
                    d_dz(i+3,2)=dz(i,2);
                    d_dz(i+3,columns+5)=dz(i,columns-1);
                end
                for i=1:1:rows
                    d_dz(i+3,1)=dz(i,3);
                    d_dz(i+3,columns+6)=dz(i,columns-2);
                end
                
                for i=1:1:columns+6
                    d_dz(3,i)=d_dz(4,i);
                    d_dz(rows+4,i)=d_dz(rows+3,i);
                end
                for i=1:1:columns+6
                    d_dz(2,i)=d_dz(5,i);
                    d_dz(rows+5,i)=d_dz(rows+2,i);
                end
                for i=1:1:columns+6
                    d_dz(1,i)=d_dz(6,i);
                    d_dz(rows+6,i)=d_dz(rows+1,i);
                end
                for l=4:1:columns+3
                for i=4:1:rows+3
                    e_dz=d_dz(i-3:i+3,l-3:l+3);
                    f_dz=e_dz;
                    for j=1:1:7
                        for k=1:1:7
                         
                            z_dz(i-3,l-3)=std(reshape(f_dz,1,(7*7)));
                            
                        end
                    end
                end
                end
                nstd=z_dz./(z_dx+z_dy+z_dz) ;
            xx=getappdata(0,'longitude');
            yy=getappdata(0,'latitude');
            pcolor(app.UIAxes,xx,yy,nstd)
            shading(app.UIAxes, 'interp')
            axis(app.UIAxes, 'image')
            set(app.UIAxes,'fontname','arial','fontsize',12,'linewidth',2)
            colorbar(app.UIAxes,'fontname','arial','fontsize',12);
            xlabel(app.UIAxes,'E-W','fontname','arial','FontWeight','bold','fontsize',12);
            ylabel(app.UIAxes,'N-S','fontname','arial','FontWeight','bold','fontsize',12);
            title(app.UIAxes,'Normalized Standard Deviation (7X7)','fontname','arial','FontWeight','bold','fontsize',12);
            setappdata(0,'nstd7_7',nstd)
        end

        % Callback function: EditField, SaveDataButton
        function SaveDataButtonPushed(app, event)
            A=getappdata(0,'gravity');
            B=getappdata(0,'cordinate');
            xx=getappdata(0,'xx');
            yy=getappdata(0,'yy');
            [row,col]=size(A);
            long=B(3,1):B(2,1):(B(3,1)+(B(2,1)*(col-1)));
            lat=B(3,2):B(1,2):(B(3,2)+(B(1,2)*(row-1)));
            [xx,yy]=meshgrid(long,lat);
            x=reshape(xx,(row*col),1);
            y=reshape(yy,(row*col),1);
            name=app.EditField.Value;
            data=getappdata(0,name);
            data=reshape(data,(row*col),1);
            xlswrite(name,[x y data])
        end

        % Button pushed function: SAVEALLButton
        function SAVEALLButtonPushed(app, event)
            A=getappdata(0,'gravity');
            B=getappdata(0,'cordinate');
            xx=getappdata(0,'xx');
            yy=getappdata(0,'yy');
            [row,col]=size(A);
            long=B(3,1):B(2,1):(B(3,1)+(B(2,1)*(col-1)));
            lat=B(3,2):B(1,2):(B(3,2)+(B(1,2)*(row-1)));
            [xx,yy]=meshgrid(long,lat);
            x=reshape(xx,(row*col),1);
            y=reshape(yy,(row*col),1);
            dx=getappdata(0,'dx');
            dx=reshape(dx,(row*col),1);
            xlswrite('dx',[x y dx])
            dy=getappdata(0,'dy');
            dy=reshape(dy,(row*col),1);
            xlswrite('dy',[x y dy])
            dz=getappdata(0,'dz');
            dz=reshape(dz,(row*col),1);
            xlswrite('dz',[x y dz])
            dzz=getappdata(0,'dzz');
            dzz=reshape(dzz,(row*col),1);
            xlswrite('dzz',[x y dzz])
            thd=getappdata(0,'thd');
            thd=reshape(thd,(row*col),1);
            xlswrite('thd',[x y thd])
            
            asa=getappdata(0,'asa');
            asa=reshape(asa,(row*col),1);
            xlswrite('asa',[x y asa])
            
            theta=getappdata(0,'theta');
            theta=reshape(theta,(row*col),1);
            xlswrite('theta',[x y theta])
            
            tilt=getappdata(0,'tilt');
            tilt=reshape(tilt,(row*col),1);
            xlswrite('tilt',[x y tilt])
            
            etilt=getappdata(0,'etilt');
            etilt=reshape(etilt,(row*col),1);
            xlswrite('etilt',[x y etilt])
            
            ethdr=getappdata(0,'ethdr');
            ethdr=reshape(ethdr,(row*col),1);
            xlswrite('ethdr',[x y ethdr])
            
            taas=getappdata(0,'taas');
            taas=reshape(taas,(row*col),1);
            xlswrite('taas',[x y taas])
            
            tahg=getappdata(0,'tahg');
            tahg=reshape(tahg,(row*col),1);
            xlswrite('tahg',[x y tahg])
            
            thvh=getappdata(0,'thvh');
            thvh=reshape(thvh,(row*col),1);
            xlswrite('thvh',[x y thvh])
            
            tdx=getappdata(0,'tdx');
            tdx=reshape(tdx,(row*col),1);
            xlswrite('tdx',[x y tdx])
            
            hta=getappdata(0,'hta');
            hta=reshape(hta,(row*col),1);
            xlswrite('hta',[x y hta])
            
            neda=getappdata(0,'neda');
            neda=reshape(neda,(row*col),1);
            xlswrite('neda',[x y neda])
            
            HT=getappdata(0,'HT');
            HT=reshape(HT,(row*col),1);
            xlswrite('HT',[x y HT])
            
            ebthd=getappdata(0,'ebthd');
            ebthd=reshape(ebthd,(row*col),1);
            xlswrite('ebthd',[x y ebthd])
            
            taebthd=getappdata(0,'taebthd');
            taebthd=reshape(taebthd,(row*col),1);
            xlswrite('taebthd',[x y taebthd])
            
            nas=getappdata(0,'nas');
            nas=reshape(nas,(row*col),1);
            xlswrite('nas',[x y nas])
            
            nsas=getappdata(0,'nsas');
            nsas=reshape(nsas,(row*col),1);
            xlswrite('nsas',[x y nsas])
            
            ntas=getappdata(0,'ntas');
            ntas=reshape(ntas,(row*col),1);
            xlswrite('ntas',[x y ntas])
            
            mntas=getappdata(0,'mntas');
            mntas=reshape(mntas,(row*col),1);
            xlswrite('mntas',[x y mntas])
            
            thdrb=getappdata(0,'thdrb');
            thdrb=reshape(thdrb,(row*col),1);
            xlswrite('thdrb',[x y thdrb])
            
            asb=getappdata(0,'asb');
            asb=reshape(asb,(row*col),1);
            xlswrite('asb',[x y asb])
            
            tbhd=getappdata(0,'tbhd');
            tbhd=reshape(tbhd,(row*col),1);
            xlswrite('tbhd',[x y tbhd])
            
            ptm=getappdata(0,'ptm');
            ptm=reshape(ptm,(row*col),1);
            xlswrite('thd',[x y ptm])
            
            etm=getappdata(0,'etm');
            etm=reshape(etm,(row*col),1);
            xlswrite('etm',[x y etm])
            
            nthd3_3=getappdata(0,'nthd3_3');
            nthd3_3=reshape(nthd3_3,(row*col),1);
            xlswrite('nthd3_3',[x y nthd3_3])
            
            nnthd3_3=getappdata(0,'nnthd3_3');
            nnthd3_3=reshape(nnthd3_3,(row*col),1);
            xlswrite('nnthd3_3',[x y nnthd3_3])
            
            bhd3_3=getappdata(0,'bhd3_3');
            bhd3_3=reshape(bhd3_3,(row*col),1);
            xlswrite('bhd3_3',[x y bhd3_3])
            
            nstd3_3=getappdata(0,'nstd3_3');
            nstd3_3=reshape(nstd3_3,(row*col),1);
            xlswrite('nstd3_3',[x y nstd3_3])
            
            nthd5_5=getappdata(0,'nthd5_5');
            nthd5_5=reshape(nthd5_5,(row*col),1);
            xlswrite('nthd5_5',[x y nthd5_5])
            
            nnthd5_5=getappdata(0,'nnthd5_5');
            nnthd5_5=reshape(nnthd5_5,(row*col),1);
            xlswrite('nnthd5_5',[x y nnthd5_5])
            
            bhd5_5=getappdata(0,'bhd5_5');
            bhd5_5=reshape(bhd5_5,(row*col),1);
            xlswrite('bhd5_5',[x y bhd5_5])
            
            nstd5_5=getappdata(0,'nstd5_5');
            nstd5_5=reshape(nstd5_5,(row*col),1);
            xlswrite('nstd5_5',[x y nstd5_5])
            
            nthd7_7=getappdata(0,'nthd7_7');
            nthd7_7=reshape(nthd7_7,(row*col),1);
            xlswrite('nthd7_7',[x y nthd7_7])
            
            nnthd7_7=getappdata(0,'nnthd7_7');
            nnthd7_7=reshape(nnthd7_7,(row*col),1);
            xlswrite('nnthd7_7',[x y nnthd7_7])
            
            bhd7_7=getappdata(0,'bhd7_7');
            bhd7_7=reshape(bhd7_7,(row*col),1);
            xlswrite('bhd7_7',[x y bhd7_7])
            
            nstd7_7=getappdata(0,'nstd7_7');
            nstd7_7=reshape(nstd7_7,(row*col),1);
            xlswrite('nstd7_7',[x y nstd7_7])
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            close(app.GMEDGEUIFigure)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GMEDGEUIFigure = uifigure;
            app.GMEDGEUIFigure.Name = 'GMEDGE';
            app.ImportGravityMagneticDataASCIIButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.ImportGravityMagneticDataASCIIButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGravityMagneticDataASCIIButtonPushed, true);
            app.ImportGravityMagneticDataASCIIButton.Text = 'Import Gravity/Magnetic Data (ASCII)';
            app.UIAxes = uiaxes(app.GMEDGEUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.dxButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.dxButton.ButtonPushedFcn = createCallbackFcn(app, @dxButtonPushed, true);
            app.dxButton.Text = 'dx';
            app.dyButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.dyButton.ButtonPushedFcn = createCallbackFcn(app, @dyButtonPushed, true);
            app.dyButton.Text = 'dy';
            app.dzButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.dzButton.ButtonPushedFcn = createCallbackFcn(app, @dzButtonPushed, true);
            app.dzButton.Text = 'dz';
            app.dzzButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.dzzButton.ButtonPushedFcn = createCallbackFcn(app, @dzzButtonPushed, true);
            app.dzzButton.Text = 'dzz';
            app.THDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.THDButton.ButtonPushedFcn = createCallbackFcn(app, @THDButtonPushed, true);
            app.THDButton.Text = 'THD';
            app.ASAButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.ASAButton.ButtonPushedFcn = createCallbackFcn(app, @ASAButtonPushed, true);
            app.ASAButton.Text = 'ASA';
            app.THETAButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.THETAButton.ButtonPushedFcn = createCallbackFcn(app, @THETAButtonPushed, true);
            app.THETAButton.Text = 'THETA';
            app.TILTButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.TILTButton.ButtonPushedFcn = createCallbackFcn(app, @TILTButtonPushed, true);
            app.TILTButton.Text = 'TILT';
            app.PTMButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.PTMButton.ButtonPushedFcn = createCallbackFcn(app, @PTMButtonPushed, true);
            app.PTMButton.Text = 'PTM';
            app.ETMButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.ETMButton.ButtonPushedFcn = createCallbackFcn(app, @ETMButtonPushed, true);
            app.ETMButton.Text = 'ETM';
            app.TAHGButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.TAHGButton.ButtonPushedFcn = createCallbackFcn(app, @TAHGButtonPushed, true);
            app.TAHGButton.Text = 'TAHG';
            app.TDXButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.TDXButton.ButtonPushedFcn = createCallbackFcn(app, @TDXButtonPushed, true);
            app.TDXButton.Text = 'TDX';
            app.ASBButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.ASBButton.ButtonPushedFcn = createCallbackFcn(app, @ASBButtonPushed, true);
            app.ASBButton.Text = 'ASB';
            app.THDRBButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.THDRBButton.ButtonPushedFcn = createCallbackFcn(app, @THDRBButtonPushed, true);
            app.THDRBButton.Text = 'THDRB';
            app.EBTHDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.EBTHDButton.ButtonPushedFcn = createCallbackFcn(app, @EBTHDButtonPushed, true);
            app.EBTHDButton.Text = 'EBTHD';
            app.TAEBTHDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.TAEBTHDButton.ButtonPushedFcn = createCallbackFcn(app, @TAEBTHDButtonPushed, true);
            app.TAEBTHDButton.Text = 'TAEBTHD';
            app.NASButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.NASButton.ButtonPushedFcn = createCallbackFcn(app, @NASButtonPushed, true);
            app.NASButton.Text = 'NAS';
            app.NSASButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.NSASButton.ButtonPushedFcn = createCallbackFcn(app, @NSASButtonPushed, true);
            app.NSASButton.Text = 'NSAS';
            app.NTASButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.NTASButton.ButtonPushedFcn = createCallbackFcn(app, @NTASButtonPushed, true);
            app.NTASButton.Text = 'NTAS';
            app.MNTASButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.MNTASButton.ButtonPushedFcn = createCallbackFcn(app, @MNTASButtonPushed, true);
            app.MNTASButton.Text = 'MNTAS';
            app.TBHDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.TBHDButton.ButtonPushedFcn = createCallbackFcn(app, @TBHDButtonPushed, true);
            app.TBHDButton.Text = 'TBHD';
            app.HTAButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.HTAButton.ButtonPushedFcn = createCallbackFcn(app, @HTAButtonPushed, true);
            app.HTAButton.Text = 'HTA';
            app.N_ED_AButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.N_ED_AButton.ButtonPushedFcn = createCallbackFcn(app, @N_ED_AButtonPushed, true);
            app.N_ED_AButton.Text = 'N_ED_A';
            app.HTButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.HTButton.ButtonPushedFcn = createCallbackFcn(app, @HTButtonPushed, true);
            app.HTButton.Text = 'HT';
            app.TAASButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.TAASButton.ButtonPushedFcn = createCallbackFcn(app, @TAASButtonPushed, true);
            app.TAASButton.Text = 'TAAS';
            app.THVHButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.THVHButton.ButtonPushedFcn = createCallbackFcn(app, @THVHButtonPushed, true);
            app.THVHButton.Text = 'THVH';
            app.ETILTButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.ETILTButton.ButtonPushedFcn = createCallbackFcn(app, @ETILTButtonPushed, true);
            app.ETILTButton.Text = 'ETILT';
            app.ETHDRButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.ETHDRButton.ButtonPushedFcn = createCallbackFcn(app, @ETHDRButtonPushed, true);
            app.ETHDRButton.Text = 'ETHDR';
            app.GradientBasedEdgeDetectionLabel = uilabel(app.GMEDGEUIFigure);
            app.GradientBasedEdgeDetectionLabel.Text = 'Gradient Based Edge Detection';
            app.NTHDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.NTHDButton.ButtonPushedFcn = createCallbackFcn(app, @NTHDButtonPushed, true);
            app.NTHDButton.Text = 'NTHD';
            app.NNTHDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.NNTHDButton.ButtonPushedFcn = createCallbackFcn(app, @NNTHDButtonPushed, true);
            app.NNTHDButton.Text = 'NNTHD';
            app.BHDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.BHDButton.ButtonPushedFcn = createCallbackFcn(app, @BHDButtonPushed, true);
            app.BHDButton.Text = 'BHD';
            app.NSTDButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.NSTDButton.ButtonPushedFcn = createCallbackFcn(app, @NSTDButtonPushed, true);
            app.NSTDButton.Text = 'NSTD';
            app.SaveDataButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SaveResultLabel = uilabel(app.GMEDGEUIFigure);
            app.SaveResultLabel.Text = 'Save Result';
            app.NTHDButton_2 = uibutton(app.GMEDGEUIFigure, 'push');
            app.NTHDButton_2.ButtonPushedFcn = createCallbackFcn(app, @NTHDButton_2Pushed, true);
            app.NTHDButton_2.Text = 'NTHD';
            app.NNTHDButton_2 = uibutton(app.GMEDGEUIFigure, 'push');
            app.NNTHDButton_2.ButtonPushedFcn = createCallbackFcn(app, @NNTHDButton_2Pushed, true);
            app.NNTHDButton_2.Text = 'NNTHD';
            app.BHDButton_2 = uibutton(app.GMEDGEUIFigure, 'push');
            app.BHDButton_2.ButtonPushedFcn = createCallbackFcn(app, @BHDButton_2Pushed, true);
            app.BHDButton_2.Text = 'BHD';
            app.NSTDButton_2 = uibutton(app.GMEDGEUIFigure, 'push');
            app.NSTDButton_2.ButtonPushedFcn = createCallbackFcn(app, @NSTDButton_2Pushed, true);
            app.NSTDButton_2.Text = 'NSTD';
            app.NTHDButton_3 = uibutton(app.GMEDGEUIFigure, 'push');
            app.NTHDButton_3.ButtonPushedFcn = createCallbackFcn(app, @NTHDButton_3Pushed, true);
            app.NTHDButton_3.Text = 'NTHD';
            app.NNTHDButton_3 = uibutton(app.GMEDGEUIFigure, 'push');
            app.NNTHDButton_3.ButtonPushedFcn = createCallbackFcn(app, @NNTHDButton_3Pushed, true);
            app.NNTHDButton_3.Text = 'NNTHD';
            app.BHDButton_3 = uibutton(app.GMEDGEUIFigure, 'push');
            app.BHDButton_3.ButtonPushedFcn = createCallbackFcn(app, @BHDButton_3Pushed, true);
            app.BHDButton_3.Text = 'BHD';
            app.NSTDButton_3 = uibutton(app.GMEDGEUIFigure, 'push');
            app.NSTDButton_3.ButtonPushedFcn = createCallbackFcn(app, @NSTDButton_3Pushed, true);
            app.NSTDButton_3.Text = 'NSTD';
            app.MovingwindowbasedEdgeDetectionLabel = uilabel(app.GMEDGEUIFigure);
            app.MovingwindowbasedEdgeDetectionLabel.Text = 'Moving window based Edge Detection';
            app.X3MovingWindowLabel = uilabel(app.GMEDGEUIFigure);
            app.X3MovingWindowLabel.Text = '3X3 Moving Window';
            app.x5MovingWindowLabel = uilabel(app.GMEDGEUIFigure);
            app.x5MovingWindowLabel.Text = '5x5 Moving Window';
            app.X7MovingWindowLabel = uilabel(app.GMEDGEUIFigure);
            app.X7MovingWindowLabel.Text = '7X7 Moving Window';
            app.EditField = uieditfield(app.GMEDGEUIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.EXITButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.SAVEALLButton = uibutton(app.GMEDGEUIFigure, 'push');
            app.SAVEALLButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEALLButtonPushed, true);
            app.SAVEALLButton.Text = 'SAVE ALL';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GMEDGE

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GMEDGEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GMEDGEUIFigure)
        end
    end
end